classdef SpeechEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        speechenhancement            matlab.ui.Figure
        TabGroup_3                   matlab.ui.container.TabGroup
        MagnitudeResponseTab         matlab.ui.container.Tab
        magres                       matlab.ui.control.UIAxes
        PhaseRepsonseTab             matlab.ui.container.Tab
        phasres                      matlab.ui.control.UIAxes
        AudioPlayerPanel             matlab.ui.container.Panel
        SaveButton_2                 matlab.ui.control.Button
        ProcessedAudioButton         matlab.ui.control.Button
        NoisyAudioButton             matlab.ui.control.Button
        InputAudioButton             matlab.ui.control.Button
        NoiseGeneratorPanel          matlab.ui.container.Panel
        NoiseTypesDropDown           matlab.ui.control.DropDown
        NoiseTypesLabel              matlab.ui.control.Label
        NoiseLevelSlider             matlab.ui.control.Slider
        NoiseLevelLabel              matlab.ui.control.Label
        InputPanel                   matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        micon                        matlab.ui.control.Image
        micoff                       matlab.ui.control.Image
        SaveButton                   matlab.ui.control.Button
        UploadButton                 matlab.ui.control.Button
        RecordAudioSwitch            matlab.ui.control.ToggleSwitch
        RecordAudioSwitchLabel       matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        TimeDomainTab                matlab.ui.container.Tab
        InputAudioTDomain            matlab.ui.control.UIAxes
        FrequencyDomainTab           matlab.ui.container.Tab
        InputAudioFDomain            matlab.ui.control.UIAxes
        TimeDomainwnoiseTab_2        matlab.ui.container.Tab
        OutnTDomain                  matlab.ui.control.UIAxes
        FrequencyDomainwnoiseTab_2   matlab.ui.container.Tab
        OutnFDomain                  matlab.ui.control.UIAxes
        TabGroup_2                   matlab.ui.container.TabGroup
        TimeDomainwonoiseTab_2       matlab.ui.container.Tab
        OutwnTDomain                 matlab.ui.control.UIAxes
        FrequencyDomainwonoiseTab_2  matlab.ui.container.Tab
        OutwnFDomain                 matlab.ui.control.UIAxes
        TypesofFilters               matlab.ui.container.ButtonGroup
        KaiserWindowButton           matlab.ui.control.ToggleButton
        EllipticButton               matlab.ui.control.ToggleButton
        ChebyshevButton              matlab.ui.control.ToggleButton
        ButterworthButton            matlab.ui.control.ToggleButton
    end

    % global functions & variables
    properties (Access = private)
        recObj;     % store recorded audio for playing
        rec;        % store audio recording data
        nsrecObj;   % store audio with noise for playing
        nsrec;      % store audio with noise data
        filrecObj;  % store filtered audio for playing
        filrec;     % store filtered audio data
        Fs;         % store sampling frequency
    end

    methods (Access = private)
        function cal_domain (app, option, x)
            t=(0:length(x)-1)/app.Fs;                                           % % calculate time of signal
            n = length(x);                                                  % find the fast fourier transform
            xfft = app.Fs/2*linspace(0,1,n/2+1); 
            yfft = abs(fft(x,n))/n;
            %yfft = 20*log10(yfft);                                       
            switch option
                case 1
                    plot(app.InputAudioTDomain, t, x);                      % plot input time domain
                    plot(app.InputAudioFDomain, xfft, 2*yfft(1:n/2+1));  % plot input frequency domain
                    %xfft(1:n/2), yfft(1:n/2)
                case 2
                    plot(app.OutnTDomain, t, x);
                    plot(app.OutnFDomain, xfft, 2*yfft(1:n/2+1));
                case 3
                    plot(app.OutwnTDomain, t, x);
                    plot(app.OutwnFDomain, xfft, 2*yfft(1:n/2+1));
                otherwise
                    return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RecordAudioSwitch
        function RecordAudioSwitchValueChanged(app, event)
            audrec = app.RecordAudioSwitch.Value;
            if audrec == "On"
                app.Fs = 44100; nBits = 16; nChannels = 1; ID = -1;         % default audio input device 
                app.recObj = audiorecorder(app.Fs, nBits, nChannels, ID);   % create audiorecorder
                app.micon.Visible = "on";                                    % change recorder icon
                app.micoff.Visible = "off";
                app.Lamp.Enable = 1;
                record(app.recObj);                                     % start recording
            elseif audrec == "Off"
                app.micon.Visible = "off";                                    % change recorder icon
                app.micoff.Visible = "on";
                app.Lamp.Enable = 0;
                stop(app.recObj);                                       % stop recording
                app.recObj = app.recObj;                             % increase recording volume
                app.rec = getaudiodata(app.recObj);                     % store audio data
                cal_domain(app, 1, app.rec);               % call function to plot time domain graph
                NoiseTypesDropDownValueChanged(app);
            end
        end

        % Button pushed function: InputAudioButton
        function InputAudioButtonPushed(app, event)
             if isempty(app.recObj)
                uialert(app.speechenhancement,"No Audio Output","Warning","Icon","error");
                return; 
            end
            play(app.recObj)                                            % play input audio 
        end

        % Close request function: speechenhancement
        function speechenhancementCloseRequest(app, event)
            % prompt user to confirm exit request
            msg=sprintf('Close the app?\nPress Yes to exit.'); %create the message
            if uiconfirm(app.speechenhancement,msg,'Exit',"Options", ...
                    {'Yes','Cancel'},"DefaultOption",1,"CancelOption",2) == "Yes"
                delete(app);
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,filepath]=uigetfile('*.wav');                     % prompt user to choose audio file
            figure(app.speechenhancement);                              % refocus on the app
            if isequal(filename,0)                                      % if user cancel the option
                return;
            else
                [app.rec,app.Fs] = audioread(fullfile(filepath,filename));  % read the audio file
            end
            app.recObj = audioplayer(app.rec,app.Fs);                       % store the audio file for playing
            uialert(app.speechenhancement, "Audio " + filename + " loaded!","Info","Icon","info");
            cal_domain(app, 1, app.rec);
            NoiseTypesDropDownValueChanged(app);
        end

        % Value changed function: NoiseTypesDropDown
        function NoiseTypesDropDownValueChanged(app, event)
            if isempty(app.rec)
                uialert(app.speechenhancement,"No Audio Input","Warning","Icon","error");
                return; 
            end
            ns = app.NoiseTypesDropDown.Value;
            switch ns
                case "Random Noise"
                    STD = app.NoiseLevelSlider.Value/20000;                 % calculate noise level of random noise
                    app.nsrec = app.rec + STD*randn(size(app.rec));         % apply noise to input signal
                case "White Noise"
                    SNR = 500/app.NoiseLevelSlider.Value;                  % calculate noise level of AWGN 
                    app.nsrec = awgn(app.rec, SNR, 'measured');             % apply awgn
            end
            app.nsrecObj = audioplayer(app.nsrec,app.Fs);                   % set audioplayer of noisy signal
            cal_domain(app, 2, app.nsrec);
            TypesofFiltersSelectionChanged(app);                            % call filter selection to update on filter signal
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.recObj)
                uialert(app.speechenhancement,"No Audio Input","Warning","Icon","error");
                return; 
            end
            [filename, pathname] = uiputfile('*.wav', 'File name for output'); % let user save file
            figure(app.speechenhancement);                              % refocus on the app
            if isnumeric(filename) %user cancel
                return;
            else
                fullname = fullfile(pathname, filename);
                audiowrite(fullname, app.rec, app.Fs)
            end                
            uialert(app.speechenhancement,"File Saved!","Info","Icon","info");
        end

        % Button pushed function: NoisyAudioButton
        function NoisyAudioButtonPushed(app, event)
            if isempty(app.nsrecObj)
                uialert(app.speechenhancement,"No Audio Output","Warning","Icon","error");
                return; 
            end
            play(app.nsrecObj)
        end

        % Selection changed function: TypesofFilters
        function TypesofFiltersSelectionChanged(app, event)
            if isempty(app.rec)
                uialert(app.speechenhancement,"No Audio Input","Warning","Icon","error");
                return; 
            end
            Wn = [500 3200]/(app.Fs/2);             % Desired Cutoff Frequency
            Rp = 3;                                 % Passband Ripple
            Rs = 40;                                % Stopband Ripple
            if app.ButterworthButton.Value == 1
                %[n,Wn] = buttord(Wp, Ws, Rp, Rs);
                [b,a] = butter(4, Wn, "bandpass");
            elseif app.ChebyshevButton.Value == 1
                %[n,Wn] = cheb2ord(Wp, Ws, Rp, Rs);
                [b,a] = cheby2(4, Rs, Wn, "bandpass");
            elseif app.EllipticButton.Value == 1
                %[n,Wn] = ellipord(Wp, Ws, Rp, Rs);
                [b,a] = ellip(4, Rp, Rs, Wn, "bandpass");
            elseif app.KaiserWindowButton.Value == 1
                fc = [400 500 2500 3000];
                m = [0 2 0];
                d = [0.05 0.1 0.05];
                [n,Wn,beta,ftype] = kaiserord(fc,m,d,app.Fs);
                n = n + rem(n,2);
                hh = fir1(n,Wn,ftype,kaiser(n+1,beta),'noscale');
            end
            if app.KaiserWindowButton.Value == 1
                app.filrec = filter(hh, 1, app.nsrec);
                [h,f] = freqz(hh,1,[],app.Fs);
            else
                app.filrec = filter(b, a, app.nsrec);
                [h,f] = freqz(b,a,[],app.Fs);                               % find frequency response of filter
            end
            plot(app.magres,f,20*log10(abs(h)));                    % plot frequency response in dB vs frequency
            plot(app.phasres,f,unwrap(angle(h))*180/pi);            % plot phase response
            app.filrecObj = audioplayer(app.filrec, app.Fs);
            cal_domain(app, 3, app.filrec);
        end

        % Value changed function: NoiseLevelSlider
        function NoiseLevelSliderValueChanged(app, event)
            if isempty(app.rec)
                uialert(app.speechenhancement,"No Audio Input","Warning","Icon","error");
                return; 
            end
            NoiseTypesDropDownValueChanged(app);
        end

        % Button pushed function: ProcessedAudioButton
        function ProcessedAudioButtonPushed(app, event)
            if isempty(app.filrecObj)
                uialert(app.speechenhancement,"No Audio Output","Warning","Icon","error");
                return; 
            end
            play(app.filrecObj)
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            if isempty(app.filrecObj)
                uialert(app.speechenhancement,"No Audio Output","Warning","Icon","error");
                return; 
            end
            [filename, pathname] = uiputfile('*.wav', 'File name for output'); % let user save file
            figure(app.speechenhancement);                              % refocus on the app
            if isnumeric(filename) %user cancel
                return;
            else
                fullname = fullfile(pathname, filename);
                audiowrite(fullname, app.filrec, app.Fs)
            end                
            uialert(app.speechenhancement,"File Saved!","Info","Icon","info");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.speechenhancement = uifigure('Visible', 'off');
            app.speechenhancement.Name = 'MATLAB App';
            app.speechenhancement.CloseRequestFcn = createCallbackFcn(app, @speechenhancementCloseRequest, true);
            app.TypesofFilters = uibuttongroup(app.speechenhancement);
            app.TypesofFilters.SelectionChangedFcn = createCallbackFcn(app, @TypesofFiltersSelectionChanged, true);
            app.TypesofFilters.Title = 'Types of Filters';
            app.ButterworthButton = uitogglebutton(app.TypesofFilters);
            app.ButterworthButton.Text = 'Butterworth';
            app.ButterworthButton.Value = true;
            app.ChebyshevButton = uitogglebutton(app.TypesofFilters);
            app.ChebyshevButton.Text = 'Chebyshev';
            app.EllipticButton = uitogglebutton(app.TypesofFilters);
            app.EllipticButton.Text = 'Elliptic';
            app.KaiserWindowButton = uitogglebutton(app.TypesofFilters);
            app.KaiserWindowButton.Text = 'Kaiser Window';
            app.TabGroup_2 = uitabgroup(app.speechenhancement);
            app.TimeDomainwonoiseTab_2 = uitab(app.TabGroup_2);
            app.TimeDomainwonoiseTab_2.Title = 'Time Domain w/o noise';
            app.OutwnTDomain = uiaxes(app.TimeDomainwonoiseTab_2);
            title(app.OutwnTDomain, 'Filtered Output Audio')
            xlabel(app.OutwnTDomain, 'Time (s)')
            ylabel(app.OutwnTDomain, 'Amplitude')
            zlabel(app.OutwnTDomain, 'Z')
            app.FrequencyDomainwonoiseTab_2 = uitab(app.TabGroup_2);
            app.FrequencyDomainwonoiseTab_2.Title = 'Frequency Domain w/o noise';
            app.OutwnFDomain = uiaxes(app.FrequencyDomainwonoiseTab_2);
            title(app.OutwnFDomain, 'Filtered Output Audio')
            xlabel(app.OutwnFDomain, 'Frequency (Hz)')
            ylabel(app.OutwnFDomain, 'Amplitude')
            zlabel(app.OutwnFDomain, 'Z')
            app.TabGroup = uitabgroup(app.speechenhancement);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.InputAudioTDomain = uiaxes(app.TimeDomainTab);
            title(app.InputAudioTDomain, 'Input Audio')
            xlabel(app.InputAudioTDomain, 'Time (s)')
            ylabel(app.InputAudioTDomain, 'Amplitude')
            zlabel(app.InputAudioTDomain, 'Z')
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.InputAudioFDomain = uiaxes(app.FrequencyDomainTab);
            title(app.InputAudioFDomain, 'Input Audio')
            xlabel(app.InputAudioFDomain, 'Frequency (Hz)')
            ylabel(app.InputAudioFDomain, 'Amplitude')
            zlabel(app.InputAudioFDomain, 'Z')
            app.TimeDomainwnoiseTab_2 = uitab(app.TabGroup);
            app.TimeDomainwnoiseTab_2.Title = 'Time Domain w/ noise';
            app.OutnTDomain = uiaxes(app.TimeDomainwnoiseTab_2);
            title(app.OutnTDomain, 'Output Audio with Noise')
            xlabel(app.OutnTDomain, 'Time (s)')
            ylabel(app.OutnTDomain, 'Amplitude')
            zlabel(app.OutnTDomain, 'Z')
            app.FrequencyDomainwnoiseTab_2 = uitab(app.TabGroup);
            app.FrequencyDomainwnoiseTab_2.Title = 'Frequency Domain w/ noise';
            app.OutnFDomain = uiaxes(app.FrequencyDomainwnoiseTab_2);
            title(app.OutnFDomain, 'Output Audio with Noise')
            xlabel(app.OutnFDomain, 'Frequency (Hz)')
            ylabel(app.OutnFDomain, 'Amplitude')
            zlabel(app.OutnFDomain, 'Z')
            app.InputPanel = uipanel(app.speechenhancement);
            app.InputPanel.Title = 'Input';
            app.RecordAudioSwitchLabel = uilabel(app.InputPanel);
            app.RecordAudioSwitchLabel.Text = 'Record Audio';
            app.RecordAudioSwitch = uiswitch(app.InputPanel, 'toggle');
            app.RecordAudioSwitch.ValueChangedFcn = createCallbackFcn(app, @RecordAudioSwitchValueChanged, true);
            app.UploadButton = uibutton(app.InputPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadButton.Text = 'Upload';
            app.SaveButton = uibutton(app.InputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveButton.Text = 'Save';
            app.micoff = uiimage(app.InputPanel);
            app.micoff.ImageSource = fullfile(pathToMLAPP, 'micoff.png');
            app.micon = uiimage(app.InputPanel);
            app.micon.Visible = 'off';
            app.micon.ImageSource = fullfile(pathToMLAPP, 'micon.png');
            app.Lamp = uilamp(app.InputPanel);
            app.Lamp.Enable = 'off';
            app.NoiseGeneratorPanel = uipanel(app.speechenhancement);
            app.NoiseGeneratorPanel.Title = 'Noise Generator';
            app.NoiseLevelLabel = uilabel(app.NoiseGeneratorPanel);
            app.NoiseLevelLabel.Text = 'Noise Level';
            app.NoiseLevelSlider = uislider(app.NoiseGeneratorPanel);
            app.NoiseLevelSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseLevelSliderValueChanged, true);
            app.NoiseTypesLabel = uilabel(app.NoiseGeneratorPanel);
            app.NoiseTypesLabel.Text = 'Noise Types ';
            app.NoiseTypesDropDown = uidropdown(app.NoiseGeneratorPanel);
            app.NoiseTypesDropDown.Items = {'Random Noise', 'White Noise'};
            app.NoiseTypesDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypesDropDownValueChanged, true);
            app.NoiseTypesDropDown.Value = 'Random Noise';
            app.AudioPlayerPanel = uipanel(app.speechenhancement);
            app.AudioPlayerPanel.Title = 'Audio Player';
            app.InputAudioButton = uibutton(app.AudioPlayerPanel, 'push');
            app.InputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputAudioButtonPushed, true);
            app.InputAudioButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.InputAudioButton.Text = 'Input Audio';
            app.NoisyAudioButton = uibutton(app.AudioPlayerPanel, 'push');
            app.NoisyAudioButton.ButtonPushedFcn = createCallbackFcn(app, @NoisyAudioButtonPushed, true);
            app.NoisyAudioButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.NoisyAudioButton.Text = 'Noisy Audio';
            app.ProcessedAudioButton = uibutton(app.AudioPlayerPanel, 'push');
            app.ProcessedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessedAudioButtonPushed, true);
            app.ProcessedAudioButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.ProcessedAudioButton.Text = 'Processed Audio';
            app.SaveButton_2 = uibutton(app.AudioPlayerPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveButton_2.Text = 'Save';
            app.TabGroup_3 = uitabgroup(app.speechenhancement);
            app.MagnitudeResponseTab = uitab(app.TabGroup_3);
            app.MagnitudeResponseTab.Title = 'Magnitude Response';
            app.magres = uiaxes(app.MagnitudeResponseTab);
            title(app.magres, 'Magnitude Response of filter')
            xlabel(app.magres, 'Frequency (Hz)')
            ylabel(app.magres, 'Amplitude (dB)')
            zlabel(app.magres, 'Z')
            app.PhaseRepsonseTab = uitab(app.TabGroup_3);
            app.PhaseRepsonseTab.Title = 'Phase Repsonse';
            app.phasres = uiaxes(app.PhaseRepsonseTab);
            title(app.phasres, 'Phase Response of filter')
            xlabel(app.phasres, 'Frequency (Hz)')
            ylabel(app.phasres, 'Phase')
            zlabel(app.phasres, 'Z')
            % Show the figure after all components are created
            app.speechenhancement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeechEnhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.speechenhancement)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.speechenhancement)
        end
    end
end