classdef Rectifier_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RectifierSimulatorApp         matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        MaximumPointEditField         matlab.ui.control.NumericEditField
        MaximumPointEditFieldLabel    matlab.ui.control.Label
        RipplePeaktoPeakVoltageVEditField  matlab.ui.control.NumericEditField
        RipplePeaktoPeakVoltageVEditFieldLabel  matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        LabelSwitch                   matlab.ui.control.Switch
        LabelSwitchLabel              matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Logo                          matlab.ui.control.Image
        FigureLabel                   matlab.ui.control.Label
        VoltageSourceLabel            matlab.ui.control.Label
        ResistanceValueLabel          matlab.ui.control.Label
        CapacitanceValueLabel         matlab.ui.control.Label
        Half_Wave_with_Capacitor      matlab.ui.control.Image
        Full_Wave                     matlab.ui.control.Image
        Full_Wave_with_Capacitor      matlab.ui.control.Image
        Half_Wave                     matlab.ui.control.Image
        InputPanel                    matlab.ui.container.Panel
        ResistanceSpinner             matlab.ui.control.Spinner
        ResistanceSpinnerLabel        matlab.ui.control.Label
        CapacitanceFSpinner           matlab.ui.control.Spinner
        CapacitanceFSpinnerLabel      matlab.ui.control.Label
        TypesofCircuitsDropDown       matlab.ui.control.DropDown
        TypesofCircuitsDropDownLabel  matlab.ui.control.Label
        FrequencyHzSpinner            matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel       matlab.ui.control.Label
        AmplitudeVSpinner             matlab.ui.control.Spinner
        AmplitudeVSpinnerLabel        matlab.ui.control.Label
        CapacitorFilterCheckBox       matlab.ui.control.CheckBox
        OutputPanel                   matlab.ui.container.Panel
        AutoCheckBox                  matlab.ui.control.CheckBox
        HoldCheckBox                  matlab.ui.control.CheckBox
        ShowasRMSValueCheckBox        matlab.ui.control.CheckBox
        SampleTimesSpinner            matlab.ui.control.Spinner
        SampleTimesSpinnerLabel       matlab.ui.control.Label
        SimulationTimesSpinner        matlab.ui.control.Spinner
        SimulationTimesSpinnerLabel   matlab.ui.control.Label
        SimulateButton                matlab.ui.control.Button
        Border                        matlab.ui.control.Image
        InputSignalGraph              matlab.ui.control.UIAxes
        OutputSignalGraph             matlab.ui.control.UIAxes
    end

    %set global variables to let all function have access to these values
    properties (Access = private)
        Amp;
        Freq;
        res;
        simultime;
        output;
        t;
    end
        methods (Access = private)
            %Change Figure for different rectifiers
            function capacitor_off_figure_half(app)
                app.FigureLabel.Text="Half-Wave Rectifier";
                app.Half_Wave.Visible=1;
                app.Full_Wave.Visible=0;
                app.Half_Wave_with_Capacitor.Visible=0;
                app.Full_Wave_with_Capacitor.Visible=0;
            end
            function capacitor_off_figure_full(app)
                app.FigureLabel.Text="Full-Wave Rectifier";
                app.Half_Wave.Visible=0;
                app.Full_Wave.Visible=1;
                app.Half_Wave_with_Capacitor.Visible=0;
                app.Full_Wave_with_Capacitor.Visible=0;
            end
            function capacitor_on_figure_half(app)
                app.FigureLabel.Text="Half-Wave Rectifier with Capacitor Filter";
                app.Half_Wave.Visible=0;
                app.Full_Wave.Visible=0;
                app.Half_Wave_with_Capacitor.Visible=1;
                app.Full_Wave_with_Capacitor.Visible=0;
            end
            function capacitor_on_figure_full(app)
                app.FigureLabel.Text="Full-Wave Rectifier with Capacitor Filter";
                app.Half_Wave.Visible=0;
                app.Full_Wave.Visible=0;
                app.Half_Wave_with_Capacitor.Visible=0;
                app.Full_Wave_with_Capacitor.Visible=1;
            end
            
            %Toggle labelling on figure
            function toggle_label_off(app)
                app.VoltageSourceLabel.Visible=0; 
                app.ResistanceValueLabel.Visible=0;
                app.CapacitanceValueLabel.Visible=0;
            end
            function toggle_label_on(app)
                app.Lamp.Enable=1;
                app.VoltageSourceLabel.Visible=1;
                app.ResistanceValueLabel.Visible=1;
                app.CapacitanceValueLabel.Visible=1;
            end
            
            %calculate ripple voltage
            function ripplevol(app)
                %get values of resistance and capacitance
                R = app.ResistanceSpinner.Value;
                C = app.CapacitanceFSpinner.Value;
                outputlen=length(app.output); %length of data array
                %calculate minimum ripple voltage
                if app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier"
                    Vrmin = app.Amp*(exp(-1/(app.Freq*R*C)));
                elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier"
                    Vrmin = app.Amp*(exp(-1/(2*app.Freq*R*C)));
                end
                %calculate for ripple peak to peak
                app.RipplePeaktoPeakVoltageVEditField.Value=app.Amp-Vrmin;
                %extract max values at every waveforms
                dpwave=round(1/(4*(app.res*app.Freq)));  %datasize per 1/4 wave
                %remove data in except data in between minimum ripple,
                %maximum ripple and the first half waveform
                for n=1:outputlen
                    if n+1>outputlen
                        break;
                        %break the loop to avoid error at last if statement
                    elseif n>outputlen-dpwave && app.output(n+1)-app.output(n)<=0 && app.output(end)-app.output(outputlen-1)<=0
                        %break the loop when at the last half waveform if
                        %the waveform is going down
                        break;
                    elseif app.output(n)<Vrmin && n>dpwave || app.output(n+1)-app.output(n)<=0 
                        app.output(n)=NaN;
                        %replace ripple range with NaN
                    end
                end
                %fill up all data in data with ripple
                app.output=fillmissing(app.output,"linear",'SamplePoints',app.t);
                %plot output
                plot(app.OutputSignalGraph,app.t,app.output,"LineStyle","-.",'LineWidth',1.2)
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            %get user input (amplitude and frequency)
            app.Amp = app.AmplitudeVSpinner.Value;
            app.Freq = app.FrequencyHzSpinner.Value;
            
            %declaring simulation time
            app.simultime = app.SimulationTimesSpinner.Value;
            app.res = app.SampleTimesSpinner.Value;
            %Warning for invalid input
            if app.Amp==0 
                uialert(app.RectifierSimulatorApp,"Please enter a value for Amplitude!","Warning!");
                return;
            elseif  app.simultime<(1/app.Freq) || app.res>(1/(10*app.Freq))
                uialert(app.RectifierSimulatorApp,"Sample time or Simulation time is too short for a full Waveform!","Warning!");
                return;
            end
            %set time array 
            app.t=0:app.res:app.simultime;
            
            %calculate and plot input signals
            input = app.Amp*(sin(2*pi*app.Freq*app.t));
            app.InputSignalGraph.YLim=[-app.Amp-1,app.Amp+1];
            plot(app.InputSignalGraph,app.t,input,'LineWidth',1.2)
            
            %calculate RMS value 
            if app.ShowasRMSValueCheckBox.Value==1 && app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier"
                app.Amp=app.Amp/2;
            elseif app.ShowasRMSValueCheckBox.Value==1 && app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier"
                app.Amp=app.Amp/sqrt(2);
            end
            
            %calculate output signal
            
            %Half-Wave Output without capacitor calculations
            if app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==0
                app.output = app.Amp*(sin(2*pi*app.Freq*app.t));
                app.output(app.output<0)=0;
                plot(app.OutputSignalGraph,app.t,app.output,'LineWidth',1.2,'DisplayName',"Half-Wave")
            elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==0
                %Full-Wave output signal without capacitor calculations
                app.output=abs(app.Amp*(sin(2*pi*app.Freq*app.t)));
                plot(app.OutputSignalGraph,app.t,app.output,'LineWidth',1.2,'DisplayName',"Full-Wave")
            elseif app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==1
                %Half-Wave output with capacitor calculations
                app.output = app.Amp*(sin(2*pi*app.Freq*app.t));
                app.output(app.output<0)=0;
                ripplevol(app);
            elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==1
                %Full-Wave output with capacitor calculations
                app.output=abs(app.Amp*(sin(2*pi*app.Freq*app.t)));
                ripplevol(app);
            end
            
            %set output Y axis limit to compare
            app.OutputSignalGraph.YLim=[-app.Amp-1,app.Amp+1];
            app.MaximumPointEditField.Value=max(app.output);
        end

        % Value changed function: CapacitorFilterCheckBox
        function CapacitorFilterCheckBoxValueChanged(app, event)
            %run to ensure the label and auto time values change 
            AutoCheckBoxValueChanged(app);
            LabelSwitchValueChanged(app);
            %change capacitor editable value
            if app.CapacitorFilterCheckBox.Value == 1
                %turn on all input options for capacitor filter
                if app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier"
                    capacitor_on_figure_half(app)
                elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier"
                    capacitor_on_figure_full(app)
                end    
                app.ResistanceSpinner.Enable=1;
                app.CapacitanceFSpinner.Enable=1;
                app.RipplePeaktoPeakVoltageVEditField.Enable=1;
                app.RipplePeaktoPeakVoltageVEditFieldLabel.Enable=1;
            else
                %turn off all input options for capcitor filter
                if app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier"
                    capacitor_off_figure_half(app)
                elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier"
                    capacitor_off_figure_full(app)
                end
                app.CapacitanceFSpinner.Value=0.001;
                app.CapacitanceFSpinner.Enable=0;
                app.ResistanceSpinner.Enable=0;
                app.ResistanceSpinner.Value=50;
                app.RipplePeaktoPeakVoltageVEditField.Enable=0;
                app.RipplePeaktoPeakVoltageVEditField.Value=0;
                app.RipplePeaktoPeakVoltageVEditFieldLabel.Enable=0;
            end
        end

        % Value changed function: TypesofCircuitsDropDown
        function TypesofCircuitsDropDownValueChanged(app, event)
            %change capacitor editable value
            LabelSwitchValueChanged(app);
            if app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value == 0
                capacitor_off_figure_half(app);
            elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value == 0
                capacitor_off_figure_full(app);
            elseif app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value == 1
                capacitor_on_figure_half(app);
            elseif app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value == 1
                capacitor_on_figure_full(app);
            end
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            %hold the plotted graph
            if app.HoldCheckBox.Value == 1
                hold(app.InputSignalGraph,"on");
                hold(app.OutputSignalGraph, "on");
            elseif app.HoldCheckBox.Value == 0
                hold(app.OutputSignalGraph, "off");
                hold(app.InputSignalGraph,"off");
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            %clear plotted graph 
            cla (app.OutputSignalGraph);
            cla (app.InputSignalGraph);
            app.MaximumPointEditField.Value=0;
            app.RipplePeaktoPeakVoltageVEditField.Value=0;
        end

        % Value changed function: AmplitudeVSpinner
        function AmplitudeVSpinnerValueChanged(app, event)
            %change Labelling
            LabelSwitchValueChanged(app);
        end

        % Value changed function: FrequencyHzSpinner
        function FrequencyHzSpinnerValueChanged(app, event)
            %Change Labelling
            LabelSwitchValueChanged(app);
            AutoCheckBoxValueChanged(app);
        end

        % Value changed function: ResistanceSpinner
        function ResistanceSpinnerValueChanged(app, event)
            %change Labelling or the auto time for graph
            LabelSwitchValueChanged(app);
            AutoCheckBoxValueChanged(app);
        end

        % Value changed function: CapacitanceFSpinner
        function CapacitanceFSpinnerValueChanged(app, event)
            %change labelling
            LabelSwitchValueChanged(app);
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            %calculate the values for 3 waveforms and the resolution of
            %graph
            if app.AutoCheckBox.Value ==1
                app.SampleTimesSpinner.Enable=0;
                app.SimulationTimesSpinner.Enable=0;
                if app.CapacitorFilterCheckBox.Value==1
                    app.SampleTimesSpinner.Value=1/(app.ResistanceSpinner.Value*app.FrequencyHzSpinner.Value*100);
                elseif app.CapacitorFilterCheckBox.Value==0
                    app.SampleTimesSpinner.Value=1/(app.FrequencyHzSpinner.Value*100);
                end
                app.SimulationTimesSpinner.Value=3/app.FrequencyHzSpinner.Value;
            else
                app.SampleTimesSpinner.Enable=1;
                app.SimulationTimesSpinner.Enable=1;
            end
        end

        % Close request function: RectifierSimulatorApp
        function RectifierSimulatorAppCloseRequest(app, event)
            %ask user to confirm whether to exit the app
            msg=sprintf('Are you sure to close the app?\nPress Yes to exit.'); %create the message
            if uiconfirm(app.RectifierSimulatorApp,msg,'Exit',"Options", ...
                    {'Yes','Cancel'},"DefaultOption",1,"CancelOption",2) == "Yes"
                delete(app);
            end
        end

        % Image clicked function: Logo
        function LogoImageClicked(app, event)
            %show info about the app
            msg = sprintf('Rectifier Simulator\nCreated by: Lee En Hao\nYear: 2021');
            uialert(app.RectifierSimulatorApp,msg,"Info","Icon","info"); %shows info of application
        end

        % Value changed function: LabelSwitch
        function LabelSwitchValueChanged(app, event)
            %toggle labelling for all 4 reftification cases
            if app.LabelSwitch.Value == "Off"
                app.Lamp.Enable=0;
                toggle_label_off(app);
            elseif app.LabelSwitch.Value == "On" && app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==0
                toggle_label_on(app);
                app.VoltageSourceLabel.Text = app.AmplitudeVSpinner.Value+"V"+newline+app.FrequencyHzSpinner.Value+"Hz";
                %set empty sting for resistance and capacitance because without capacitor filter
                app.ResistanceValueLabel.Text = " "; 
                app.CapacitanceValueLabel.Text = " ";
                %set position for labels
                app.VoltageSourceLabel.Position = [197 490 70 48];
            elseif app.LabelSwitch.Value == "On" && app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==0
                toggle_label_on(app);
                app.VoltageSourceLabel.Text = app.AmplitudeVSpinner.Value+"V"+newline+app.FrequencyHzSpinner.Value+"Hz";
                app.ResistanceValueLabel.Text = " ";
                app.CapacitanceValueLabel.Text = " ";
                app.VoltageSourceLabel.Position = [170 560 70 48];
            elseif app.LabelSwitch.Value == "On" && app.TypesofCircuitsDropDown.Value == "Half-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==1
                toggle_label_on(app);
                app.VoltageSourceLabel.Text = app.AmplitudeVSpinner.Value+"V"+newline+app.FrequencyHzSpinner.Value+"Hz";
                app.ResistanceValueLabel.Text = app.ResistanceSpinner.Value+"Ω";
                app.CapacitanceValueLabel.Text = app.CapacitanceFSpinner.Value+"F";
                app.VoltageSourceLabel.Position = [197 490 70 48];
                app.ResistanceValueLabel.Position = [418 490 68 22];
                app.CapacitanceValueLabel.Position = [535 485 61 28];
            elseif app.LabelSwitch.Value == "On" && app.TypesofCircuitsDropDown.Value == "Full-Wave Rectifier" && app.CapacitorFilterCheckBox.Value==1
                toggle_label_on(app);
                app.VoltageSourceLabel.Text = app.AmplitudeVSpinner.Value+"V"+newline+app.FrequencyHzSpinner.Value+"Hz";
                app.ResistanceValueLabel.Text = app.ResistanceSpinner.Value+"Ω";
                app.CapacitanceValueLabel.Text = app.CapacitanceFSpinner.Value+"F";
                app.VoltageSourceLabel.Position = [170 560 70 48];
                app.ResistanceValueLabel.Position = [522 507 68 22];
                app.CapacitanceValueLabel.Position = [592 507 61 28];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %combine both array into 2-D array for saving purpose
            datasaved=[app.t;app.output];
            %is datasaved is empty, return warning
            if isempty(datasaved)==1
                uialert(app.RectifierSimulatorApp,"Please ensure there is graph plotted!","Warning!");
            %if no file name input, return warning
            elseif app.FileNameEditField.Value=="" 
                uialert(app.RectifierSimulatorApp,"Please enter a file name!","Warning!");
            else
                %stores data in txt with given file name, with folder named
                %ESA Rectifier
                writematrix(datasaved,fullfile("ESA Rectifier",app.FileNameEditField.Value),"FileType","text");
                uialert(app.RectifierSimulatorApp,"File Saved!","Info","Icon","info");
                app.FileNameEditField.Value="";
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,filepath]=uigetfile('*.txt'); %let user choose which file to load
            figure(app.RectifierSimulatorApp);
            if isequal(filename,0)
                return;
            else
                dataread=readmatrix(fullfile(filepath,filename)); %read array from the chosen file
            end 
            [row,~]=size(dataread); %count how many rows is in the txt
            errorflag=0;
            if ~isfinite(dataread) %if not finite, set flag 
                errorflag=1;
            elseif isempty(dataread) %if empty, set flag 
                errorflag=1;
            elseif row~=2       %if not 2D array, set flag 
                errorflag=1;
            end    
            if errorflag==1 %if invalid file, return error
                uialert(app.RectifierSimulatorApp,"Invalid File!","Warning!")
                return; %return function
            end 
            uialert(app.RectifierSimulatorApp,"File Loaded!","Info","Icon","info"); %prompt user the file is loaded 
            plot(app.OutputSignalGraph,dataread(1,:),dataread(2,:),'LineWidth',1.2,"LineStyle","-"); %plot loaded file
            app.MaximumPointEditField.Value=max(dataread(1,:)); %shows maximum point
            app.RipplePeaktoPeakVoltageVEditField.Value=0; %reset ripple voltage values
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RectifierSimulatorApp = uifigure('Visible', 'off');
            app.RectifierSimulatorApp.Name = 'Rectifier Simulator';
            app.RectifierSimulatorApp.Icon = 'Logo_Background.png';
            app.RectifierSimulatorApp.CloseRequestFcn = createCallbackFcn(app, @RectifierSimulatorAppCloseRequest, true);
            app.OutputSignalGraph = uiaxes(app.RectifierSimulatorApp);
            title(app.OutputSignalGraph, 'Output Signal')
            xlabel(app.OutputSignalGraph, 'Time (s)')
            ylabel(app.OutputSignalGraph, 'Amplitude (V)')
            zlabel(app.OutputSignalGraph, 'Z')
            app.OutputSignalGraph.XAxisLocation = 'origin';
            app.OutputSignalGraph.YAxisLocation = 'origin';
            app.InputSignalGraph = uiaxes(app.RectifierSimulatorApp);
            title(app.InputSignalGraph, 'Input Signal')
            xlabel(app.InputSignalGraph, 'Time (s)')
            ylabel(app.InputSignalGraph, 'Amplitude (V)')
            zlabel(app.InputSignalGraph, 'Z')
            app.InputSignalGraph.XAxisLocation = 'origin';
            app.InputSignalGraph.YAxisLocation = 'origin';
            app.Border = uiimage(app.RectifierSimulatorApp);
            app.Border.ImageSource = 'Border.jpg';
            app.OutputPanel = uipanel(app.RectifierSimulatorApp);
            app.SimulateButton = uibutton(app.OutputPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SimulationTimesSpinnerLabel = uilabel(app.OutputPanel);
            app.SimulationTimesSpinnerLabel.Text = 'Simulation Time (s)';
            app.SimulationTimesSpinner = uispinner(app.OutputPanel);
            app.SimulationTimesSpinner.Step = 0.01;
            app.SimulationTimesSpinner.Limits = [0 100];
            app.SimulationTimesSpinner.Value = 0.05;
            app.SampleTimesSpinnerLabel = uilabel(app.OutputPanel);
            app.SampleTimesSpinnerLabel.Text = 'Sample Time (s)';
            app.SampleTimesSpinner = uispinner(app.OutputPanel);
            app.SampleTimesSpinner.Step = 1e-09;
            app.SampleTimesSpinner.Limits = [1e-12 0.1];
            app.SampleTimesSpinner.Value = 1e-06;
            app.ShowasRMSValueCheckBox = uicheckbox(app.OutputPanel);
            app.ShowasRMSValueCheckBox.Text = 'Show as RMS Value';
            app.HoldCheckBox = uicheckbox(app.OutputPanel);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Text = 'Hold';
            app.AutoCheckBox = uicheckbox(app.OutputPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.InputPanel = uipanel(app.RectifierSimulatorApp);
            app.CapacitorFilterCheckBox = uicheckbox(app.InputPanel);
            app.CapacitorFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CapacitorFilterCheckBoxValueChanged, true);
            app.CapacitorFilterCheckBox.Text = 'Capacitor Filter';
            app.AmplitudeVSpinnerLabel = uilabel(app.InputPanel);
            app.AmplitudeVSpinnerLabel.Text = 'Amplitude (V)';
            app.AmplitudeVSpinner = uispinner(app.InputPanel);
            app.AmplitudeVSpinner.Limits = [0 999999999];
            app.AmplitudeVSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVSpinnerValueChanged, true);
            app.FrequencyHzSpinnerLabel = uilabel(app.InputPanel);
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.InputPanel);
            app.FrequencyHzSpinner.Limits = [1 10000];
            app.FrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSpinnerValueChanged, true);
            app.FrequencyHzSpinner.Value = 150;
            app.TypesofCircuitsDropDownLabel = uilabel(app.InputPanel);
            app.TypesofCircuitsDropDownLabel.Text = 'Types of Circuits';
            app.TypesofCircuitsDropDown = uidropdown(app.InputPanel);
            app.TypesofCircuitsDropDown.Items = {'Half-Wave Rectifier', 'Full-Wave Rectifier'};
            app.TypesofCircuitsDropDown.ValueChangedFcn = createCallbackFcn(app, @TypesofCircuitsDropDownValueChanged, true);
            app.TypesofCircuitsDropDown.Value = 'Half-Wave Rectifier';
            app.CapacitanceFSpinnerLabel = uilabel(app.InputPanel);
            app.CapacitanceFSpinnerLabel.Text = 'Capacitance (F)';
            app.CapacitanceFSpinner = uispinner(app.InputPanel);
            app.CapacitanceFSpinner.Step = 1e-06;
            app.CapacitanceFSpinner.Limits = [1e-07 0.001];
            app.CapacitanceFSpinner.ValueChangedFcn = createCallbackFcn(app, @CapacitanceFSpinnerValueChanged, true);
            app.CapacitanceFSpinner.Enable = 'off';
            app.CapacitanceFSpinner.Value = 0.001;
            app.ResistanceSpinnerLabel = uilabel(app.InputPanel);
            app.ResistanceSpinnerLabel.Text = 'Resistance (Ω)';
            app.ResistanceSpinner = uispinner(app.InputPanel);
            app.ResistanceSpinner.Step = 10;
            app.ResistanceSpinner.Limits = [1 99999];
            app.ResistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @ResistanceSpinnerValueChanged, true);
            app.ResistanceSpinner.Enable = 'off';
            app.ResistanceSpinner.Value = 50;
            app.Half_Wave = uiimage(app.RectifierSimulatorApp);
            app.Half_Wave.ImageSource = 'Half-Wave Rectifier-1.jpg';
            app.Full_Wave_with_Capacitor = uiimage(app.RectifierSimulatorApp);
            app.Full_Wave_with_Capacitor.Visible = 'off';
            app.Full_Wave_with_Capacitor.ImageSource = 'Full-Wave Rectifier With Capacitor-1.jpg';
            app.Full_Wave = uiimage(app.RectifierSimulatorApp);
            app.Full_Wave.Visible = 'off';
            app.Full_Wave.ImageSource = 'Full-Wave Rectifier-1.jpg';
            app.Half_Wave_with_Capacitor = uiimage(app.RectifierSimulatorApp);
            app.Half_Wave_with_Capacitor.Visible = 'off';
            app.Half_Wave_with_Capacitor.ImageSource = 'Half-Wave Rectifier With Capacitor-1.jpg';
            app.CapacitanceValueLabel = uilabel(app.RectifierSimulatorApp);
            app.CapacitanceValueLabel.Text = '';
            app.ResistanceValueLabel = uilabel(app.RectifierSimulatorApp);
            app.ResistanceValueLabel.Text = '';
            app.VoltageSourceLabel = uilabel(app.RectifierSimulatorApp);
            app.VoltageSourceLabel.Text = '';
            app.FigureLabel = uilabel(app.RectifierSimulatorApp);
            app.FigureLabel.Text = 'Half-Wave Rectifier';
            app.Logo = uiimage(app.RectifierSimulatorApp);
            app.Logo.ImageClickedFcn = createCallbackFcn(app, @LogoImageClicked, true);
            app.Logo.ImageSource = 'Logo.png';
            app.Lamp = uilamp(app.RectifierSimulatorApp);
            app.Lamp.Enable = 'off';
            app.LabelSwitchLabel = uilabel(app.RectifierSimulatorApp);
            app.LabelSwitch = uiswitch(app.RectifierSimulatorApp, 'slider');
            app.LabelSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelSwitchValueChanged, true);
            app.SaveButton = uibutton(app.RectifierSimulatorApp, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.RectifierSimulatorApp);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RectifierSimulatorApp, 'text');
            app.LoadButton = uibutton(app.RectifierSimulatorApp, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel = uipanel(app.RectifierSimulatorApp);
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RipplePeaktoPeakVoltageVEditFieldLabel = uilabel(app.Panel);
            app.RipplePeaktoPeakVoltageVEditFieldLabel.Enable = 'off';
            app.RipplePeaktoPeakVoltageVEditFieldLabel.Text = 'Ripple Peak to Peak Voltage (V)';
            app.RipplePeaktoPeakVoltageVEditField = uieditfield(app.Panel, 'numeric');
            app.RipplePeaktoPeakVoltageVEditField.Editable = 'off';
            app.RipplePeaktoPeakVoltageVEditField.Enable = 'off';
            app.MaximumPointEditFieldLabel = uilabel(app.Panel);
            app.MaximumPointEditFieldLabel.Text = 'Maximum Point';
            app.MaximumPointEditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.RectifierSimulatorApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectifier_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RectifierSimulatorApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RectifierSimulatorApp)
        end
    end
end